classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        q5Slider       matlab.ui.control.Slider
        q5SliderLabel  matlab.ui.control.Label
        q4Slider       matlab.ui.control.Slider
        q4SliderLabel  matlab.ui.control.Label
        q3Slider       matlab.ui.control.Slider
        q3SliderLabel  matlab.ui.control.Label
        q2Slider       matlab.ui.control.Slider
        q2SliderLabel  matlab.ui.control.Label
        q1Slider       matlab.ui.control.Slider
        q1SliderLabel  matlab.ui.control.Label
        q0Slider       matlab.ui.control.Slider
        q0SliderLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pi_;
        p;
        H;
        DhT;
        L;
    end
    
    methods (Access = private)
        
        function H = calculateHTM(~, theta, d, a, alpha)
            H = [cos(theta)  -sin(theta)*cos(alpha)  sin(theta)*sin(alpha)   a*cos(theta);
                 sin(theta)  cos(theta)*cos(alpha)   -cos(theta)*sin(alpha)  a*sin(theta);
                 0           sin(alpha)              cos(alpha)              d;
                 0           0                       0                       1];
        end
        
        function [] = drawRobot(app, theta, n)
            Hn = calculateHTM(app, theta, app.DhT(n, 1), app.DhT(n, 2), app.DhT(n, 3));
            app.H(:, :, n) = Hn;
            
            for i = n:6
                app.p(i, :) = app.pi_(i, :);
            end
            
            for i = n:6
                H_ = eye(4);
                for j = 1:i
                    H_ = H_ * app.H(:,:,j);
                end
                app.p(i, :) = app.transformPoint(H_, app.p(i, :));
            end
            
            x = zeros(1, 7);
            y = zeros(1, 7);
            z = zeros(1, 7);
            
            for i=2:7
                x(i) = app.p(i-1, 1);
                y(i) = app.p(i-1, 2);
                z(i) = app.p(i-1, 3);
            end
            
            
            plot3(app.UIAxes, x, y, z, 'Color', 'b');
            
            app.drawCircle([0 0 0]);
            for i = 1:5
                app.drawCircle(app.p(i, :));
            end
            
            app.drawGripper();
            
        end
        
        function [] = drawCircle(app, C) 
            x = C(1);
            y = C(2);
            z = C(3);
            
            hold(app.UIAxes, 'on');
            plot3(app.UIAxes, x, y, z, 'o', 'Color', '#EDB120', 'MarkerFaceColor', '#EDB120');
            hold(app.UIAxes, 'off');
        end
        
        function [] = drawGripper(app)
            offset = 0.005;
            H_ = eye(4);
            for i = 1:6
                H_ = H_ * app.H(:,:,i);
            end
            
            A = app.transformPoint(H_, [0 -offset 0]);
            B = app.transformPoint(H_, [0 offset 0]);            
            C = app.transformPoint(H_, [0 -offset offset]);
            D = app.transformPoint(H_, [0 offset offset]);
            
            hold(app.UIAxes, 'on');
            plot3(app.UIAxes, [A(1), B(1)], [A(2), B(2)], [A(3), B(3)], 'Color', 'b');
            plot3(app.UIAxes, [A(1), C(1)], [A(2), C(2)], [A(3), C(3)], 'Color', 'b');
            plot3(app.UIAxes, [B(1), D(1)], [B(2), D(2)], [B(3), D(3)], 'Color', 'b');
            hold(app.UIAxes, 'off');
        end
        
        function [tPt] = transformPoint(~, tM, pt)
            pt = [pt 1]';
            temp = tM * pt;
            tPt = temp(1:3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.L = [0.15, 0.04, 0.035, 0.02, 0.015, 0.01];
            app.pi_ = [0 0 0;                % joint centers wrt their reference frame
                      0 0 0;
                      0 0 app.L(3);
                      0 0 0;
                      0 0 app.L(5);
                      0 0 0];
            app.p = app.pi_;
            app.H = zeros(4, 4, 6);
            app.DhT = [app.L(1)           0         pi/2;
                       0                  app.L(2)  0;
                       0                  0         pi/2;
                       app.L(3)+app.L(4)  0         pi/2;
                       0                  0         -pi/2;
                       app.L(5)+app.L(6)  0         0];
            
            app.q0Slider.Limits(1) = -3;
            app.q0Slider.Limits(2) = 3;
            app.q1Slider.Limits(1) = -3;
            app.q1Slider.Limits(2) = 3;
            app.q2Slider.Limits(1) = -3;
            app.q2Slider.Limits(2) = 3;
            app.q3Slider.Limits(1) = -3;
            app.q3Slider.Limits(2) = 3;
            app.q4Slider.Limits(1) = -3;
            app.q4Slider.Limits(2) = 3;
            app.q5Slider.Limits(1) = -3;
            app.q5Slider.Limits(2) = 3;
            xlim(app.UIAxes, [-1, 1]);
            ylim(app.UIAxes, [-1, 1]);
            zlim(app.UIAxes, [-1, 1]);
            
            for i = 1:6
                app.H(:, :, i) = calculateHTM(app, 0, app.DhT(i, 1), app.DhT(i, 2), app.DhT(i, 3));
            end
            
            app.drawRobot(0, 1);
        end

        % Value changing function: q0Slider
        function q0SliderValueChanging(app, event)
            q = event.Value;
            
            app.drawRobot(q, 1);
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            q = event.Value;
            
            app.drawRobot(q, 2);
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            q = event.Value;
            
            app.drawRobot(q, 3);
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            q = event.Value;
            
            app.drawRobot(q, 4);
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            q = event.Value;
            
            app.drawRobot(q, 5);
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            q = event.Value;
            
            app.drawRobot(q, 6);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.q0SliderLabel = uilabel(app.UIFigure);
            app.q0SliderLabel.Text = 'q0';
            app.q0Slider = uislider(app.UIFigure);
            app.q0Slider.ValueChangingFcn = createCallbackFcn(app, @q0SliderValueChanging, true);
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end